classdef PlantDiseaseDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Upload       matlab.ui.control.Button
        Background   matlab.ui.control.Image
        Segmented    matlab.ui.control.Image
        Enhancement  matlab.ui.control.Image
        Original     matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function Processing(~, OriginalImage)

            %Enhancement
            EnhanceImage = rgb2lab(OriginalImage);
            max_enhancement = 100;
            e = EnhanceImage(:,:,1)/max_enhancement;
            EnhancedImage = EnhanceImage;

            %Segmentation
            EnhancedImage(:,:,1) = adapthisteq(e)*max_enhancement;
            EnhancedImage = lab2rgb(EnhancedImage);
            
            %Resize EnhancedImage height and with to 256
            RGB = imresize(EnhancedImage,[256,256]);
            %Convert RGB to Lab Color Space
            RGB = im2double(RGB);
            cform = makecform('srgb2lab', 'AdaptedWhitePoint', whitepoint('D65'));
            ei = applycform(RGB,cform);
            
            %Define Threshold for each Channel
            channel1Min = 0.058;
            channel1Max = 99.617;
                
            channel2Min = 0;
            channel2Max = 14.601;

            channel3Min = 0;
            channel3Max = 43.442;

            %Create mask based on choosen threshold values
            BW = (ei(:,:,1) >= channel1Min ) & (ei(:,:,1) <= channel1Max) & ...
        (ei(:,:,2) >= channel2Min ) & (ei(:,:,2) <= channel2Max) & ...
        (ei(:,:,3) >= channel3Min ) & (ei(:,:,3) <= channel3Max);
            
            %Initial output based on input
            maskedRGBImage = RGB;

            %Set background where BW is false to zero
            maskedRGBImage(repmat(~BW,[1 1 3])) = 0;

            SegmentedImage = maskedRGBImage;
            
            imwrite(EnhancedImage,"EnhancedImage.jpg");
            imwrite(SegmentedImage,"SegmentedImage.jpg");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Upload
        function UploadPushed(app, event)
            %Getting image file
            [filename, filepath] = uigetfile('*.jpg;*.png;*.jpeg','Select a leaf image');
            fullname = [filepath filename];

            OriginalImage = imread(fullname);

            Processing(app,OriginalImage);

            displayEnhancement = imread("EnhancedImage.jpg");
            displaySegmented = imread("SegmentedImage.jpg");

            %Display Original Image
            app.Original = uiimage(app.UIFigure);
            app.Original.Visible = 'on';
            app.Original.Position = [40 120 165 165];
            app.Original.ImageSource = OriginalImage;
            app.Original.ScaleMethod = 'fill';
            
            %Display Enhanced Image
            app.Enhancement = uiimage(app.UIFigure);
            app.Enhancement.Visible = 'on';
            app.Enhancement.Position = [240 120 165 165];
            app.Enhancement.ImageSource = displayEnhancement;
            app.Enhancement.ScaleMethod = 'fill';

            %Display Segmented Image
            app.Segmented = uiimage(app.UIFigure);
            app.Enhancement.Visible = 'on';
            app.Segmented.Position = [440 120 165 165];
            app.Segmented.ImageSource = displaySegmented;
            app.Segmented.ScaleMethod = 'fill';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiimage(app.UIFigure);
            app.Original.Visible = 'off';
            app.Enhancement = uiimage(app.UIFigure);
            app.Enhancement.Visible = 'off';
            app.Segmented = uiimage(app.UIFigure);
            app.Segmented.Visible = 'off';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'bg.gif';
            app.Upload = uibutton(app.UIFigure, 'push');
            app.Upload.ButtonPushedFcn = createCallbackFcn(app, @UploadPushed, true);
            app.Upload.Icon = 'upload.png';
            app.Upload.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantDiseaseDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end